classdef APIMs_V6_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APIMsUIFigure                  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        LoadnewMenu                    matlab.ui.container.Menu
        OpenfolderMenu                 matlab.ui.container.Menu
        InfoMenu                       matlab.ui.container.Menu
        AboutMenu                      matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        SaveimageButton                matlab.ui.control.Button
        ColorbarDropDown_2             matlab.ui.control.DropDown
        ColorbarLabel_2                matlab.ui.control.Label
        ColorbarDropDown               matlab.ui.control.DropDown
        ColorbarLabel                  matlab.ui.control.Label
        ExcludeedgesButton             matlab.ui.control.Button
        FilterbyratioButton            matlab.ui.control.Button
        widthLabel                     matlab.ui.control.Label
        RemoveobjectswhoselengthEditField  matlab.ui.control.NumericEditField
        RemoveobjectswhoselengthEditFieldLabel  matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        HeightEditField                matlab.ui.control.NumericEditField
        HeightLabel                    matlab.ui.control.Label
        MeasurewidthbetweenEditField   matlab.ui.control.NumericEditField
        MeasurewidthbetweenEditFieldLabel  matlab.ui.control.Label
        GraphmakerButton               matlab.ui.control.Button
        pixelsAreaLabel_2              matlab.ui.control.Label
        pixelsAreaLabel                matlab.ui.control.Label
        RemoveobjectsEditField_2       matlab.ui.control.NumericEditField
        RemoveobjectsEditField_2Label  matlab.ui.control.Label
        RemoveobjectsEditField         matlab.ui.control.NumericEditField
        RemoveobjectsEditFieldLabel    matlab.ui.control.Label
        FilterbyareaButton             matlab.ui.control.Button
        PlotResultsButton              matlab.ui.control.Button
        CalculatewiresButton           matlab.ui.control.Button
        ExcludeObjectsButton           matlab.ui.control.Button
        FindObjectsButton              matlab.ui.control.Button
        Label                          matlab.ui.control.Label
        TiltangleEditField             matlab.ui.control.NumericEditField
        TiltangleEditFieldLabel        matlab.ui.control.Label
        nmLabel                        matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        xEditFieldLabel                matlab.ui.control.Label
        SelectpixelinbackgroundButton  matlab.ui.control.Button
        OpenMeasurementsfolderButton   matlab.ui.control.Button
        ObjectTypeDropDown             matlab.ui.control.DropDown
        ObjectTypeDropDownLabel        matlab.ui.control.Label
        LengthperpixelEditField        matlab.ui.control.NumericEditField
        LengthperpixelEditFieldLabel   matlab.ui.control.Label
        BackgroundremovalintensitybandwidthEditField  matlab.ui.control.NumericEditField
        BackgroundremovalintensitybandwidthEditFieldLabel  matlab.ui.control.Label
        MinimumpixelsperobjectEditField  matlab.ui.control.NumericEditField
        MinimumpixelsperobjectEditFieldLabel  matlab.ui.control.Label
        OptionalsettingsLabel          matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        LoadingpleasewaitLabel         matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        UIAxes                         matlab.ui.control.UIAxes
        byPJHLuebLabel                 matlab.ui.control.Label
        APIMsoftwareLabel              matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        ShowboundariesCheckBox         matlab.ui.control.CheckBox
        OpenImageButton                matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        UIAxes2                        matlab.ui.control.UIAxes
    end


    properties (Access = private)
        name % Name of the file
        file % Directory + name of the file
        SEM_Image % Matrix of the entire SEM Image
        length_per_pixel % Length per pixel
        tilt_angle % Tilt angle
        SEM_data % SEM_data
        resolution % Resolution of SEM_data
        new_SEM_data % After background removal
        objects % Found objects
        folder_to_save_to % Folder to save data in
        calcstate % To check whether calculations have been made or not
        imagestate % To check whether image is loaded
        object_type % Object type string
        HistoGramBOI % TEST123
        cmap % colormapping
        ColorList % List of which color belongs to which object
        calcbuttonpushed % Description
    end

    methods (Access = private)

%% Function ShowImage
        function ShowImage(app)
            size_SEM_Image = size(app.SEM_Image);
            app.UIAxes.XLim = [1 size_SEM_Image(2)];
            app.UIAxes.YLim = [1 size_SEM_Image(1)];
            app.UIAxes.Visible = 'on';
            % Fill figure with axes and remove tick labels
            app.UIAxes.Position = [0 0 app.Panel.Position(3:4)];
            % Remove title, axis labels, and tick labels
            title(app.UIAxes, []);
            xlabel(app.UIAxes, []);
            ylabel(app.UIAxes, []);
            app.UIAxes.XAxis.TickLabels = {};
            app.UIAxes.YAxis.TickLabels = {};
            % Display image and stretch to fill axes
            %I = imshow(app.SEM_Image, 'Parent', app.UIAxes, ...
            %    'XData', [1 app.UIAxes.Position(3)], ...
            %    'YData', [1 app.UIAxes.Position(4)]);
            I = imshow(app.SEM_Image, 'Parent', app.UIAxes, ...
                'XData', [1 size_SEM_Image(2)], ...
                'YData', [1 size_SEM_Image(1)]);
            % Set limits of axes
            %app.UIAxes.XLim = [0 I.XData(2)];
            %app.UIAxes.YLim = [0 I.YData(2)];
            app.calcstate = 0;
            app.imagestate = 1;
        end

%% Function SelectPixel
        function SelectPixel(app)
            if app.imagestate ==1
                app.TextArea.Value = 'Please click on the background and press "Enter".';
                [x, y] = getpts(app.UIAxes);
                x = round(mean(x));
                y = round(mean(y));
                app.xEditField.Value = x;
                app.yEditField.Value = y;

                app.TextArea.Value = ['You have selected:',strcat('(x,y) = (',num2str(x),", ", num2str(y),')')];
            else
                return;
            end
        end
% %% Function BulkCalculation
%         function BulkCalculation(app)
%             % Retrieving relevant parameters from other sources
%             contrast_parameter = app.BackgroundremovalintensitybandwidthEditField.Value;
%             object_too_small = app.MinimumpixelsperobjectEditField.Value;
%             see_bounds = app.ShowboundariesCheckBox.Value;
%             app.length_per_pixel = app.LengthperpixelEditField.Value;
%             app.tilt_angle = app.TiltangleEditField.Value;
% 
%             % Removing background
%             app.new_SEM_data = app_removebackground(app.SEM_Image, app.SEM_data, contrast_parameter, object_too_small, app.xEditField.Value, app.yEditField.Value);
% 
% 
%             % Detecting objects
%             app.objects = app_findobjects(app.new_SEM_data);
%             if isempty(app.objects)
%                 app.TextArea.Value = ["No objects have been found in the image." ;"Please check if the parameters have been set correctly and try again."];
%                 CreateStruct.Interpreter = 'tex';
%                 CreateStruct.WindowStyle = 'modal';
%                 msgbox({'\fontsize{15} No objects have been found in the image.' ,' \fontsize{15} Please check if the parameters have been set correctly and try again.'}, 'Error','error', CreateStruct);
%                 return;
%             end
% 
%             % Identifying the outline of the objects
%             [app.objects.Outline, app.objects.OutlineImageSpace] = findoutline(app.objects);
% 
%             % Exclude objects
%             app.TextArea.Value = ['Please click on found objects that you do NOT want to include in this analysis and press "Enter".'];
%             ShowBounds(app);
%             app.objects = app_excludeobjects(app.SEM_Image, app.objects);
% 
%             % For wire directionality
%             if strcmp(app.ObjectTypeDropDown.Value, 'Top view')
%                 app.objects = app_topview_orientation(app.objects, app.SEM_data);
%             end
% 
%             % Calculate length
%             app.objects = app_calclength(app.objects, app.resolution, app.tilt_angle, app.length_per_pixel);
% 
%             % Calculating width
%             top = app.HeightEditField.Value/100;
%             bottom = app.MeasurewidthbetweenEditField.Value/100;
%             app.objects = app_calcwidthV2(app.objects, app.length_per_pixel, top, bottom);
% 
%             % For HEXAGONAL wires
%             if strcmp(app.ObjectTypeDropDown.Value, 'Hexagonal/round') || strcmp(app.ObjectTypeDropDown.Value, 'Top view') %hexagonal
%                 % Calculating objects volume
%                 app.objects.Volume = pi*app.objects.Length.*(app.objects.Width./2).^2;
%                 app.objects.PhysicalVolume = pi*app.objects.PhysicalLength.*(app.objects.PhysicalWidth./2).^2;
% 
%                 % Plotting information in image
%                 plotinfo(app.SEM_Image, app.objects, see_bounds)
%                 %See_bounds parameter is now inside plotinfo
%             end
% 
%             % For CUBIC wires
%             if strcmp(app.ObjectTypeDropDown.Value,'Cubic') %cubic
%                 % Fitting cubic wires
%                 app.objects = app_cubefit(app.objects,app.length_per_pixel, app.tilt_angle);
%             end
% 
% 
%             % Plotting information in image
%             if length(app.objects.Area) <200
%                 if strcmp(app.ObjectTypeDropDown.Value,'Cubic') %if cubic
%                     app_plotcube(app.objects, app.SEM_Image)
%                     hold on
%                     visboundaries(app.objects.OutlineImageSpace,'LineStyle', ':', 'Color', 'g', 'LineWidth',1,'EnhanceVisibility',0)
%                     hold off
%                 else %if non-cubic
%                     plotinfo(app.SEM_Image, app.objects, see_bounds)
%                 end
%             else
%                 ShowImage(app)
%             end
% 
%             % Information to text box
%             [line1, line2, line3, line4, line5] = app_command_text_to_textbox(app.objects, app.name);
%             app.TextArea.Value = [line1; ''; line2; line3; line4; line5];
% 
%             % Displaying information in command window + output to file
%             [object_data, app.folder_to_save_to] = app_outputdata(app.objects, app.name, app.file, app.tilt_angle);
% 
%             % Information to table
%             object_data(:,2) = object_data(:,2)/1000;
%             app.UITable.Data = object_data;
% 
%             app.calcstate = 1;
% 
%         end

%% Function OpenFile
        function OpenFile(app)
            app.TextArea.Value = 'Please open an image to get started.';
            %Loading in the file
            [name, file, SEM_Image] = app_loadfile;
            if SEM_Image == 0
                app.TextArea.Value = 'Loading file has been cancelled...';
                return;
            else
                app.name = name;
                app.file = file;
                app.SEM_Image = SEM_Image;
                app.xEditField.Value = 0;
                app.yEditField.Value = 0;
            end

            %Reading off data from the file
            [app.length_per_pixel, app.tilt_angle] = readdata(app.file);
            if isnan(app.length_per_pixel)
                app.length_per_pixel = 0;
            else
                app.LengthperpixelEditField.Value = app.length_per_pixel;
            end
            if isnan(app.tilt_angle)
                app.tilt_angle = 0;
            elseif strcmp(app.ObjectTypeDropDown.Value,'Top view')
                app.tilt_angle = 0;
                app.TiltangleEditField.Value = app.tilt_angle;
            else
                app.TiltangleEditField.Value = app.tilt_angle;
            end

            %Splitting the image and reading off resolution
            [app.SEM_data, app.resolution] = findresolution(app.SEM_Image);


            app.TextArea.Value = strcat("File loaded: ", app.name);

            drawnow;
            figure(app.APIMsUIFigure)
            ShowImage(app)
            %if app.flag == 1
            %    OrsonIsEenHeld(app.HistoGramBOI,app.file)
            %end


        end

%% Function ShowBounds
        function ShowBounds(app)
            hold on
            visboundaries(app.UIAxes,app.objects.OutlineImageSpace)
            hold off
            %size_SEM_Image = size(app.SEM_Image);
            %app.UIAxes.XLim = [0 size_SEM_Image(2)];
            %app.UIAxes.YLim = [0 size_SEM_Image(1)];
        end

%% Function FindObjects
    function FindObjects(app)
            %Turn on loading screen
            app.TextArea.Value = [strcat("File loaded: ", app.name); 'Looking for objects, please wait...'];
            app.LoadingpleasewaitLabel.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Image.Enable = 'on';
            app.Image.Visible ='on';
            pause(0.00000001);

            % Retrieving relevant parameters from other sources
            contrast_parameter = app.BackgroundremovalintensitybandwidthEditField.Value;
            object_too_small = app.MinimumpixelsperobjectEditField.Value;
            app.length_per_pixel = app.LengthperpixelEditField.Value;
            if strcmp(app.ObjectTypeDropDown.Value,'Top view')
                app.tilt_angle = 0;
                app.TiltangleEditField.Value = 0;
            else
                app.tilt_angle = app.TiltangleEditField.Value;
            end

            % Removing background
            if islogical(app.SEM_data)
                app.SEM_data = uint8(app.SEM_data)*255;
                app.SEM_Image = uint8(app.SEM_Image)*255;
            end
            app.new_SEM_data = app_removebackground(app.SEM_Image, app.SEM_data, contrast_parameter, object_too_small, app.xEditField.Value, app.yEditField.Value);


            % Detecting objects
            app.objects = app_findobjects(app.new_SEM_data);
            if isempty(app.objects)
                app.TextArea.Value = ["No objects have been found in the image." ;"Please check if the parameters have been set correctly and try again."];
                CreateStruct.Interpreter = 'tex';
                CreateStruct.WindowStyle = 'modal';
                msgbox({'\fontsize{15} No objects have been found in the image.' ,' \fontsize{15} Please check if the parameters have been set correctly and try again.'}, 'Error','error', CreateStruct);
                return;
            end

            % Identifying the outline of the objects
            [app.objects.Outline, app.objects.OutlineImageSpace] = findoutline(app.objects);
            %ShowBounds(app);
            objects_found = length(app.objects.Area);
            app.TextArea.Value = [strcat("File loaded: ", app.name); strcat("Number of objects found: ",num2str(objects_found))];
            CalculateWires(app)
            DisplayResultsColormap(app)
            %Turn off loading screen
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Image.Enable = 'off';
            app.Image.Visible ='off';
        end
 

%% Function ExcludeObjects
        function ExcludeObjects(app)
            % Exclude objects
            app.TextArea.Value = ['Please click on found objects that you do NOT want to include in this analysis and press "Enter".'];
            %ShowBounds(app);
            %DisplayResultsColormap(app)
            old_numb_objects = length(app.objects.Area);
            app.objects = app_excludeobjects2(app.SEM_Image, app.objects, 0);
            new_numb_objects = length(app.objects.Area);
            
            ShowImage(app)
            %ShowBounds(app);
            DisplayResultsColormap(app)
            numb_obj_removed = old_numb_objects - new_numb_objects;
            app.TextArea.Value = [strcat("Number of objects removed: ", num2str(numb_obj_removed)); strcat("Total objects is now: ", num2str(new_numb_objects))];
        end
        
%% Function CalculateWires
        function CalculateWires(app)
            %Turn on loading screen
            app.TextArea.Value = ['Calculating, please wait...'];
            app.LoadingpleasewaitLabel.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Image.Enable = 'on';
            app.Image.Visible ='on';
            pause(0.000001);
            
            %For tilt angle
            if strcmp(app.ObjectTypeDropDown.Value,'Top view')
                app.tilt_angle = 0;
                app.TiltangleEditField.Value = 0;
            else
                app.tilt_angle = app.TiltangleEditField.Value;
            end

            % For wire directionality
            if strcmp(app.ObjectTypeDropDown.Value, 'Top view')
                app.objects = app_topview_orientation(app.objects, app.SEM_data);
            end

            % Calculate length
            app.objects = app_calclength(app.objects, app.resolution, app.tilt_angle, app.length_per_pixel);

            % Calculating width
            top = app.HeightEditField.Value/100;
            bottom = app.MeasurewidthbetweenEditField.Value/100;
            app.objects = app_calcwidthV2(app.objects, app.length_per_pixel, top, bottom);
            
            %For top view calculate (relative) curvature
            if strcmp(app.ObjectTypeDropDown.Value, 'Top view')
                app.objects.RelativeCurvature = (app.objects.PhysicalLength-app.objects.PhysicalWidth)/max(app.objects.PhysicalLength-app.objects.PhysicalWidth);
            end

            % For HEXAGONAL wires
            if sum(strcmp('Hexagonal/round', {'Hexagonal/round','Top view','Cubic'}))
                % Calculating objects volume
                %app.objects.Volume = pi*app.objects.Length.*(app.objects.Width./2).^2;
                %app.objects.PhysicalVolume = pi*app.objects.PhysicalLength.*(app.objects.PhysicalWidth./2).^2;
                app.objects = app_calcvolume(app.objects);
            end

            % For CUBIC wires
            if strcmp(app.ObjectTypeDropDown.Value,'Cubic') && app.calcbuttonpushed == 1 %cubic
                % Fitting cubic wires
                app.objects = app_cubefit(app.objects,app.length_per_pixel, app.tilt_angle);
            end

            % Information to text box
            [line1, line2, line3, line4, line5] = app_command_text_to_textbox(app.objects, app.name);
            app.TextArea.Value = [line1; ''; line2; line3; line4; line5];
            
            app.objects.Orientation = mod(app.objects.Orientation, 180);
            % Displaying information in command window + output to file
            [object_data, app.folder_to_save_to] = app_outputdata(app.objects, app.name, app.file, app.tilt_angle);

            % Information to table
            object_data(:,2) = object_data(:,2)/1000;
            wire_number = [1:length(object_data(:,1))]';
            app.UITable.Data = [wire_number, object_data(:,1:4)];

            app.calcstate = 1;
            
            %Turn off loading screen
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Image.Enable = 'off';
            app.Image.Visible ='off';
        end
        
%% Function PlotResults
        function PlotResults(app)
            %Turn on loading screen
            %app.TextArea.Value = ['Calculating, please wait...'];
            app.LoadingpleasewaitLabel.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Image.Enable = 'on';
            app.Image.Visible ='on';
            pause(0.000001);

            see_bounds = app.ShowboundariesCheckBox.Value;
            Exist_Column = strcmp('CubeFit',app.objects.Properties.VariableNames);
            CubeFit_present = Exist_Column(Exist_Column==1);
            if strcmp(app.ObjectTypeDropDown.Value,'Cubic') & CubeFit_present %if cubic
                app_plotcube(app.objects, app.tilt_angle, app.SEM_Image, app.cmap, app.ColorList)
                hold on
                %visboundaries(app.objects.OutlineImageSpace,'LineStyle', ':', 'Color', 'g', 'LineWidth',1,'EnhanceVisibility',0)
                hold off
            elseif length(app.objects.Area) <200
                %if non-cubic & less than 200 wires
                plotinfo(app.SEM_Image, app.objects, see_bounds)
            else
                ShowImage(app)
                DisplayResultsColormap(app)
            end

            %Turn off loading screen
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Image.Enable = 'off';
            app.Image.Visible ='off';
        end
        
%% Function FilterByArea
        function FilterByArea(app)
            upperlimit = app.RemoveobjectsEditField_2.Value/100;
            lowerlimit = app.RemoveobjectsEditField.Value/100;
            %ShowBounds(app);
            DisplayResultsColormap(app)
            old_numb_objects = length(app.objects.Area);
            

            app.objects = app_filterbyarea(app.objects, upperlimit', lowerlimit);
            
            new_numb_objects = length(app.objects.Area);
            numb_obj_removed = old_numb_objects - new_numb_objects;
            app.TextArea.Value = [strcat("Number of objects removed: ", num2str(numb_obj_removed)); strcat("Total objects is now: ", num2str(new_numb_objects))];

            ShowImage(app)
            %ShowBounds(app);
            DisplayResultsColormap(app)
        end

        
        function FilterByRatio(app)
            ratio = app.RemoveobjectswhoselengthEditField.Value;
            old_numb_objects = length(app.objects.Area);

            app.objects = app_filterbywidthheight(app.objects, ratio);

            new_numb_objects = length(app.objects.Area);
            numb_obj_removed = old_numb_objects - new_numb_objects;
            app.TextArea.Value = [strcat("Number of objects removed: ", num2str(numb_obj_removed)); strcat("Total objects is now: ", num2str(new_numb_objects))];

            ShowImage(app)
            %ShowBounds(app);
            DisplayResultsColormap(app)
        end
        
        function ExcludeEdges(app)
            old_numb_objects = length(app.objects.Area);
            
            app.objects = app_excludeobjectsatedges(app.objects, app.resolution);
            
            new_numb_objects = length(app.objects.Area);
            numb_obj_removed = old_numb_objects - new_numb_objects;
            app.TextArea.Value = [strcat("Number of objects removed: ", num2str(numb_obj_removed)); strcat("Total objects is now: ", num2str(new_numb_objects))];

            ShowImage(app)
            %ShowBounds(app);
            DisplayResultsColormap(app);
        end

%% Function DisplayResultsColormap
        function DisplayResultsColormap(app)
            %Turn on loading screen
            %app.TextArea.Value = [strcat("File loaded: ", app.name); 'Looking for objects, please wait...'];
            app.LoadingpleasewaitLabel.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Image.Enable = 'on';
            app.Image.Visible ='on';
            pause(0.00000001);

            ShowImage(app)
            what = app.ColorbarDropDown_2.Value;
            if strcmp('Width (nm)', what)
                x = app.objects.PhysicalWidth;
                %name_string = 'Width (nm)';
                colorbarlabel = 'Width (nm)';
                divby = 1;
            elseif strcmp('Length (µm)', what)
                x = app.objects.PhysicalLength;
                %name_string = 'Length (µm)';
                colorbarlabel = 'Length (µm)';
                divby = 1000;
            elseif strcmp('Orientation (°)', what)
                Exist_Column = strcmp('TopViewOrientation',app.objects.Properties.VariableNames);
                q_topview_present = Exist_Column(Exist_Column==1);
                if q_topview_present
                    x = app.objects.TopViewOrientation;
                else
                    x = app.objects.Orientation;
                end
                colorbarlabel = 'Orientation (°)';
                divby = 1;
            elseif strcmp('Volume (nm³)', what)
                x = app.objects.Volume;
                colorbarlabel = 'Volume (nm³)';
                divby = 1;
            elseif strcmp('Area (pixels)', what)
                x = app.objects.Area;
                colorbarlabel = 'Area (pixels)';
                divby = 1;
            end
            if isnan(sum(x)) || sum(x) == Inf || sum(x) == -Inf || sum(x) ==0
                uniquecolors = 1;
                uniquepoints = linspace(0,1,uniquecolors);
            else
                uniquecolors = length(unique(x));
                uniquepoints = linspace(min(x),max(x),uniquecolors);
            end
            
            app.cmap = jet(uniquecolors);
            app.ColorList = zeros(length(x),1);
            for i = 1:length(x)
                [~,closestindex] = min(abs(x(i)-uniquepoints'));
                app.ColorList(i,1) = closestindex;
            end
            
            Exist_Column = strcmp('CubeFit',app.objects.Properties.VariableNames);
            CubeFit_present = Exist_Column(Exist_Column==1);
            if strcmp(app.ObjectTypeDropDown.Value,'Cubic') & CubeFit_present %if cubic
                app_plotcube(app.objects, app.tilt_angle, app.SEM_Image, app.cmap, app.ColorList)
            else
                hold on
                for k = 1:length(app.objects.OutlineImageSpace)
                    line(app.objects.OutlineImageSpace{k}(:,2),app.objects.OutlineImageSpace{k}(:,1),'Color',app.cmap(app.ColorList(k,1), :), 'LineWidth',1.5, 'Parent', app.UIAxes)
                end
                hold off
            end
            %Do the colorbar:
            colormap(app.UIAxes2, "jet")
            cbar_axes = colorbar(app.UIAxes2, "eastoutside");
            cbar_axes.FontSize = 13;
            %holdup = ylabel(cbar_axes, name_string);
            %holdup.VerticalAlignment = 'bottom';
            %holdup.FontSize = 13;
            
            %Make sure that it still works if accidently everything is
            %deleted
            if isnan(sum(x)) || sum(x) == Inf || sum(x) == -Inf || sum(x) ==0 || max(size(x))==1
                caxis(app.UIAxes2, [0,1])
            else
                caxis(app.UIAxes2, [min(x),max(x)]/divby)
            end
            set(cbar_axes.XLabel,{'String','Rotation','Position', 'Visible'},{colorbarlabel,90,[-1.1, (sum(cbar_axes.Limits))/2], 'on'}) %[-0.8, 0.5]
            app.calcstate = 1;

            %Turn off loading screen
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Image.Enable = 'off';
            app.Image.Visible ='off';
            
        end

%% Function BigFilter
        function BigFilter(app)
            %Filter by area
            upperlimit = app.RemoveobjectsEditField_2.Value;
            lowerlimit = app.RemoveobjectsEditField.Value;
            what = app.ColorbarDropDown_2.Value;
            if strcmp('Width (nm)', what)
                x = app.objects.PhysicalWidth;
            elseif strcmp('Length (µm)', what)
                x = app.objects.PhysicalLength;
                upperlimit = app.RemoveobjectsEditField_2.Value*1000;
                lowerlimit = app.RemoveobjectsEditField.Value*1000;
            elseif strcmp('Orientation (°)', what)
                Exist_Column = strcmp('TopViewOrientation',app.objects.Properties.VariableNames);
                q_topview_present = Exist_Column(Exist_Column==1);
                if q_topview_present
                    x = app.objects.TopViewOrientation;
                else
                    x = app.objects.Orientation;
                end
            elseif strcmp('Volume (nm³)', what)
                x = app.objects.Volume;
            elseif strcmp('Area (pixels)', what)
                x = app.objects.Area;
            end

            less_than_lowerlimit = x < lowerlimit;
            more_than_upperlimit = x > upperlimit;
           %which_ones = find(less_than_lowerlimit == 1);
            which_ones = [find(less_than_lowerlimit == 1)',find(more_than_upperlimit == 1)']';
            app.objects(which_ones,:) = [];
            %x = app.objects;
            %Upper limit:
            %more_than_upperlimit = x > upperlimit;
            %which_ones = find(more_than_upperlimit == 1);
            %app.objects(which_ones,:) = [];

            DisplayResultsColormap(app)
        end
 
%% Function UpdateFilterWords
        function UpdateFilterWords(app)
            what = app.ColorbarDropDown_2.Value;
            if strcmp('Width (nm)', what)
                x = "nm, Width";
                y = "Filter by width";
                app.ColorbarDropDown.Value = 'Width (nm)';
                app.ColorbarDropDownValueChanged;
            elseif strcmp('Length (µm)', what)
                x = "µm, Length";
                y = "Filter by length";
                app.ColorbarDropDown.Value = 'Length (µm)';
                app.ColorbarDropDownValueChanged;
            elseif strcmp('Orientation (°)', what)
                x = "°, Orientation";
                y = "Filter by or.";
                app.ColorbarDropDown.Value = 'Orientation (°)';
                app.ColorbarDropDownValueChanged;
            elseif strcmp('Volume (nm³)', what)
                x = "nm³, Volume";
                y = "Filter by volume";
                app.ColorbarDropDown.Value = 'Volume (nm³)';
                app.ColorbarDropDownValueChanged;
            elseif strcmp('Area (pixels)', what)
                x = "pixels, Area";
                y = "Filter by area";
                app.ColorbarDropDown.Value = 'Area (pixels)';
                app.ColorbarDropDownValueChanged;
            end
            app.pixelsAreaLabel.Text = x;
            app.pixelsAreaLabel_2.Text = x;
            app.FilterbyareaButton.Text = y;
        end

%% Function SaveImageWithColorbar
        function SaveImageWithColorbar(app)
            %Turn on loading screen
            %app.TextArea.Value = [strcat("File loaded: ", app.name); 'Looking for objects, please wait...'];
            app.LoadingpleasewaitLabel.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Image.Enable = 'on';
            app.Image.Visible ='on';
            pause(0.00000001);

            %Writing shapes onto the image file
            I = imread(app.file);
            %If cubic draw cubes on top
            if strcmp(app.ObjectTypeDropDown.Value,'Cubic')
                for which_obj = 1:length(app.objects.Area)
                    if app.objects.CubeFit(which_obj) == 1

                        width = app.objects.xopt{which_obj}(1);
                        height = app.objects.xopt{which_obj}(2);
                        phi = app.objects.xopt{which_obj}(3);
                        theta = app.tilt_angle;
                        slant_angle = app.objects.xopt{which_obj}(4);
                        x_shift = app.objects.xopt{which_obj}(5);
                        y_shift = app.objects.xopt{which_obj}(6);

                        [x,y] = app_cubeprojection(width, height, phi, theta, slant_angle, x_shift, y_shift);

                        %Calculate extra point and set everything to fit in image space
                        %Outline per object in total image space
                        x_shift = app.objects.BoundingBox(which_obj,1)+0.5 + app.objects.BoundingBox(which_obj,3) + x_shift;
                        y_shift = app.objects.BoundingBox(which_obj,2)-0.5 +y_shift;

                        x = -x + x_shift;
                        y = y + y_shift;

                        %Setting extra points so it looks like a cube
                        extra_point1_x = x(1)+x(5)-x(6);
                        extra_point1_y = y(1)+y(5)-y(6);

                        extra_line1_x = [x(1), extra_point1_x];
                        extra_line1_y = [y(1), extra_point1_y];

                        extra_line2_x = [x(3), extra_point1_x];
                        extra_line2_y = [y(3), extra_point1_y];

                        extra_line3_x = [x(5), extra_point1_x];
                        extra_line3_y = [y(5), extra_point1_y];

                        extra_point2_x = x(2)+x(6)-x(7);
                        extra_point2_y = y(2)+y(6)-y(7);

                        extra_line4_x = [x(4), extra_point2_x];
                        extra_line4_y = [y(4), extra_point2_y];

                        extra_line5_x = [x(2), extra_point2_x];
                        extra_line5_y = [y(2), extra_point2_y];

                        extra_line6_x = [x(6), extra_point2_x];
                        extra_line6_y = [y(6), extra_point2_y];

                        %Plotting the data
                        I = insertShape(I, 'Line', [x',y'], 'Color', app.cmap(app.ColorList(which_obj,1), :)*255,  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line1_x', extra_line1_y'], 'Color', round(app.cmap(app.ColorList(which_obj,1), :)*255),  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line2_x', extra_line2_y'], 'Color', round(app.cmap(app.ColorList(which_obj,1), :)*255),  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line3_x', extra_line3_y'], 'Color', round(app.cmap(app.ColorList(which_obj,1), :)*255),  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line4_x', extra_line4_y'], 'Color', app.cmap(app.ColorList(which_obj,1), :)*255,  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line5_x', extra_line5_y'], 'Color', app.cmap(app.ColorList(which_obj,1), :)*255,  'LineWidth',1);
                        I = insertShape(I, 'Line', [extra_line6_x', extra_line6_y'], 'Color', app.cmap(app.ColorList(which_obj,1), :)*255,  'LineWidth',1);
                        
                        position = [max(x) + 5, min(y) + 10];
                        disp_number = num2str(which_obj);
                        I = insertText(I,position,disp_number,'FontSize',14,'BoxColor','red','BoxOpacity',0,'TextColor', app.cmap(app.ColorList(which_obj,1), :)*255);
                        
                        %text(max(x) + 5, min(y) + 10, disp_number, 'Fontsize', 14, 'Color', usecolor)
                    end
                end
            else %Else just draw the outlines
                for k = 1:length(app.objects.OutlineImageSpace)
                    shape = [app.objects.OutlineImageSpace{k}(:,2),app.objects.OutlineImageSpace{k}(:,1)];
                    I = insertShape(I, 'Line', shape, 'Color', app.cmap(app.ColorList(k,1), :)*255, 'LineWidth',2);
                end
            end
            imwrite(I,strcat(app.folder_to_save_to,'\','image.png'));

            %Retrieve colorbar image
            exportgraphics(app.UIAxes2,strcat(app.folder_to_save_to,'\','colorbar.png'), "Resolution",600)

            %Changes the colorbar to match nicely with the original image
            im2 = imread(strcat(app.folder_to_save_to,'\','colorbar.png'));
            im2dy = sum(im2(:,:,1)) + sum(im2(:,:,2)) + sum(im2(:,:,3));
            [~, loca] = min(im2dy);
            im2(:,1:loca-1,1) = rot90(rot90(im2(:,1:loca-1,1)));
            im2(:,1:loca-1,2) = rot90(rot90(im2(:,1:loca-1,2)));
            im2(:,1:loca-1,3) = rot90(rot90(im2(:,1:loca-1,3)));
            im2(:,:,1) = circshift(im2(:,:,1),-loca,2);
            im2(:,:,2) = circshift(im2(:,:,2),-loca,2);
            im2(:,:,3) = circshift(im2(:,:,3),-loca,2);
            im2size = size(im2);
            im2 = [255*ones(im2size(1),20,3), im2];
            imwrite(im2,strcat(app.folder_to_save_to,'\','colorbar.png'))

            %Combine the image with color and the colorbar
            out = imtile({strcat(app.folder_to_save_to,'\','image.png'), strcat(app.folder_to_save_to,'\','colorbar.png')},'BorderSize', 0);
            out1 = out(:,:,1);
            out1 = out1(:,any(out1));
            out2 = out(:,:,2);
            out2 = out2(:,any(out2));
            out3 = out(:,:,3);
            out3 = out3(:,any(out3));
            clear new_out
            new_out(:,:,1) = out1;
            new_out(:,:,2) = out2;
            new_out(:,:,3) = out3;
            
            %Decide what the file name is going to be
            what = app.ColorbarDropDown_2.Value;
            if strcmp('Width (nm)', what)
                x = "Width ";
            elseif strcmp('Length (µm)', what)
                x = "Length ";
            elseif strcmp('Orientation (°)', what)
                x = "Orientation ";
            elseif strcmp('Volume (nm³)', what)
                x = "Volume ";
            elseif strcmp('Area (pixels)', what)
                x = "Pixel area ";
            end
            filename = strcat(x,erase(app.name, ".tif"),'.png');
            imwrite(new_out, strcat(app.folder_to_save_to,'\',filename))

            %Remove the temporary files as if they have never been there
            delete(strcat(app.folder_to_save_to,'\','image.png'))
            delete(strcat(app.folder_to_save_to,'\','colorbar.png'))

            %Turn off loading screen
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Image.Enable = 'off';
            app.Image.Visible ='off';
        end
    end
    
    methods (Access = public)
        
        function appdata = UpdateData(app)
            appdata.name = app.name;                            % Name of the file
            appdata.file = app.file;                            % Directory + name of the file
            appdata.SEM_Image = app.SEM_Image;                  % Matrix of the entire SEM Image
            appdata.length_per_pixel = app.length_per_pixel;    % Length per pixel
            appdata.tilt_angle = app.tilt_angle;                % Tilt angle
            appdata.SEM_data = app.SEM_data;                    % SEM_data
            appdata.resolution = app.resolution;                % Resolution of SEM_data
            appdata.new_SEM_data = app.new_SEM_data;            % After background removal
            appdata.objects = app.objects;                      % Found objects
            appdata.folder_to_save_to = app.folder_to_save_to;  % Folder to save data in
            appdata.calcstate = app.calcstate;                  % To check whether calculations have been made or not
            appdata.imagestate = app.imagestate;                % To check whether image is loaded
            appdata.object_type = app.object_type;              % Object type string
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow;
            app.APIMsUIFigure.WindowState = 'maximized';
            %pause(0.001)
            %app.APIMsUIFigure.WindowState = 'normal';
            app.calcstate = 0;
            app.imagestate = 0;
            app.calcbuttonpushed = 0;
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            web('https://nl.linkedin.com/in/pim-lueb-957b2b206', '-browser')
        end

        % Callback function: LoadnewMenu, OpenImageButton
        function OpenImageButtonPushed(app, event)
            OpenFile(app);
        end

        % Callback function: OpenMeasurementsfolderButton, OpenfolderMenu
        function OpenMeasurementsfolderButtonPushed(app, event)
            if app.calcstate == 1
                winopen(app.folder_to_save_to)
            end
        end

        % Button pushed function: SelectpixelinbackgroundButton
        function SelectpixelinbackgroundButtonPushed(app, event)
            SelectPixel(app);
        end

        % Menu selected function: HelpMenu
        function HelpMenuSelected(app, event)
            web('https://www.youtube.com/watch?v=dQw4w9WgXcQ', '-browser')
        end

        % Button pushed function: FindObjectsButton
        function FindObjectsButtonPushed(app, event)
            %% TEST MODE
            if app.imagestate == 1

                old_UIFigure = app.APIMsUIFigure;
                % 1) Turn on the app figure's handle visibility.
                app.APIMsUIFigure.HandleVisibility = 'on';
                % 2) Make the app's axes current
                old_fig = gcf;
                set(0, 'CurrentFigure', app.APIMsUIFigure)
                set(app.APIMsUIFigure,'CurrentAxes',app.UIAxes)

                if app.xEditField.Value == 0 || app.yEditField.Value == 0
                    SelectPixel(app);
                    FindObjects(app);
                else
                    FindObjects(app);
                end
                set(0, 'CurrentFigure', old_fig)
                app.APIMsUIFigure = old_UIFigure;
            end

        end

        % Button pushed function: ExcludeObjectsButton
        function ExcludeObjectsButtonPushed(app, event)
            if app.calcstate == 1
                ExcludeObjects(app);
            end
        end

        % Button pushed function: CalculatewiresButton
        function CalculatewiresButtonPushed(app, event)
            if app.imagestate == 1 && app.calcstate==1
                app.calcbuttonpushed = 1;
                CalculateWires(app);
                DisplayResultsColormap(app);
                app.calcbuttonpushed = 0;
            end
        end

        % Button pushed function: PlotResultsButton
        function PlotResultsButtonPushed(app, event)
            if app.imagestate == 1 && app.calcstate==1
                PlotResults(app);
            end
        end

        % Button pushed function: FilterbyareaButton
        function FilterbyareaButtonPushed(app, event)
            if app.calcstate == 1
                BigFilter(app);
            end
        end

        % Button pushed function: GraphmakerButton
        function GraphmakerButtonPushed(app, event)
            if app.calcstate == 1
                app.HistoGramBOI = APIMs_histogram_app(app);
            end
        end

        % Button pushed function: FilterbyratioButton
        function FilterbyratioButtonPushed(app, event)
            if app.calcstate == 1
                FilterByRatio(app)
            end
        end

        % Button pushed function: ExcludeedgesButton
        function ExcludeedgesButtonPushed(app, event)
            if app.calcstate ==1
                ExcludeEdges(app)
            end
        end

        % Value changed function: ColorbarDropDown
        function ColorbarDropDownValueChanged(app, event)
            if app.imagestate == 1 && app.calcstate==1
               DisplayResultsColormap(app)
            end
        end

        % Value changed function: ColorbarDropDown_2
        function ColorbarDropDown_2ValueChanged(app, event)
            UpdateFilterWords(app);

        end

        % Value changed function: ObjectTypeDropDown
        function ObjectTypeDropDownValueChanged(app, event)
            if app.imagestate == 1 && app.calcstate==1
                CalculateWires(app);
                DisplayResultsColormap(app);
            end
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            if app.imagestate == 1 && app.calcstate==1
                SaveImageWithColorbar(app);
            end
        end

        % Key press function: APIMsUIFigure
        function APIMsUIFigureKeyPress(app, event)
            key = event.Key;
            if strcmp(key,'f')
                FindObjectsButtonPushed(app,event)
            elseif strcmp(key,'e')
                ExcludeObjectsButtonPushed(app,event)
            elseif strcmp(key,'d')
                ExcludeedgesButtonPushed(app,event)
            elseif strcmp(key,'a')
                FilterbyareaButtonPushed(app,event)
            elseif strcmp(key, 'c')
                CalculatewiresButtonPushed(app,event)
            elseif strcmp(key, 's')
                SaveimageButtonPushed(app,event)
            elseif strcmp(key,'o')
                OpenMeasurementsfolderButtonPushed(app,event)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APIMsUIFigure = uifigure('Visible', 'off');
            app.APIMsUIFigure.Name = 'APIMs';
            app.APIMsUIFigure.Icon = 'APIMs_Icon.png';
            app.APIMsUIFigure.KeyPressFcn = createCallbackFcn(app, @APIMsUIFigureKeyPress, true);
            app.FileMenu = uimenu(app.APIMsUIFigure);
            app.FileMenu.Text = 'File';
            app.LoadnewMenu = uimenu(app.FileMenu);
            app.LoadnewMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.LoadnewMenu.Text = 'Load new...';
            app.OpenfolderMenu = uimenu(app.FileMenu);
            app.OpenfolderMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMeasurementsfolderButtonPushed, true);
            app.OpenfolderMenu.Text = 'Open folder...';
            app.InfoMenu = uimenu(app.APIMsUIFigure);
            app.InfoMenu.Text = 'Info';
            app.AboutMenu = uimenu(app.InfoMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About...';
            app.HelpMenu = uimenu(app.InfoMenu);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @HelpMenuSelected, true);
            app.HelpMenu.Text = 'Help...';
            app.UIAxes2 = uiaxes(app.APIMsUIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.Visible = 'off';
            app.UITable = uitable(app.APIMsUIFigure);
            app.UITable.ColumnName = {'Wire'; 'Width (nm)'; 'Length (μm)'; 'Volume (nm³)'; 'Orientation (°)'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = true;
            app.OpenImageButton = uibutton(app.APIMsUIFigure, 'push');
            app.OpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @OpenImageButtonPushed, true);
            app.OpenImageButton.Text = 'Open Image';
            app.ShowboundariesCheckBox = uicheckbox(app.APIMsUIFigure);
            app.ShowboundariesCheckBox.Text = 'Show boundaries';
            app.TextArea = uitextarea(app.APIMsUIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Please open an image to get started.'};
            app.APIMsoftwareLabel = uilabel(app.APIMsUIFigure);
            app.APIMsoftwareLabel.Text = 'APIMsoftware ©';
            app.byPJHLuebLabel = uilabel(app.APIMsUIFigure);
            app.byPJHLuebLabel.Text = 'by P.J.H. Lueb    ';
            app.Panel = uipanel(app.APIMsUIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.Visible = 'off';
            app.Image = uiimage(app.Panel);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'APIMs_logo.png';
            app.LoadingpleasewaitLabel = uilabel(app.Panel);
            app.LoadingpleasewaitLabel.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.LoadingpleasewaitLabel.Text = 'Loading, please wait...';
            app.OptionalsettingsLabel = uilabel(app.APIMsUIFigure);
            app.OptionalsettingsLabel.Text = 'Optional settings:';
            app.MinimumpixelsperobjectEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.MinimumpixelsperobjectEditFieldLabel.Text = 'Minimum pixels per object';
            app.MinimumpixelsperobjectEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.MinimumpixelsperobjectEditField.Limits = [0 Inf];
            app.MinimumpixelsperobjectEditField.Value = 400;
            app.BackgroundremovalintensitybandwidthEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.BackgroundremovalintensitybandwidthEditFieldLabel.Text = 'Background removal intensity bandwidth';
            app.BackgroundremovalintensitybandwidthEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.BackgroundremovalintensitybandwidthEditField.Limits = [0 255];
            app.BackgroundremovalintensitybandwidthEditField.Value = 20;
            app.LengthperpixelEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.LengthperpixelEditFieldLabel.Text = 'Length per pixel';
            app.LengthperpixelEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.LengthperpixelEditField.Limits = [0 Inf];
            app.ObjectTypeDropDownLabel = uilabel(app.APIMsUIFigure);
            app.ObjectTypeDropDownLabel.Text = 'Object Type';
            app.ObjectTypeDropDown = uidropdown(app.APIMsUIFigure);
            app.ObjectTypeDropDown.Items = {'Hexagonal/round', 'Cubic', 'Top view'};
            app.ObjectTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectTypeDropDownValueChanged, true);
            app.ObjectTypeDropDown.Value = 'Hexagonal/round';
            app.OpenMeasurementsfolderButton = uibutton(app.APIMsUIFigure, 'push');
            app.OpenMeasurementsfolderButton.ButtonPushedFcn = createCallbackFcn(app, @OpenMeasurementsfolderButtonPushed, true);
            app.OpenMeasurementsfolderButton.Tooltip = {'(o)'};
            app.OpenMeasurementsfolderButton.Text = 'Open Measurements folder';
            app.SelectpixelinbackgroundButton = uibutton(app.APIMsUIFigure, 'push');
            app.SelectpixelinbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpixelinbackgroundButtonPushed, true);
            app.SelectpixelinbackgroundButton.Text = 'Select pixel in background';
            app.xEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.xEditFieldLabel.Text = 'x:';
            app.xEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.yEditFieldLabel.Text = 'y:';
            app.yEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.nmLabel = uilabel(app.APIMsUIFigure);
            app.nmLabel.Text = 'nm';
            app.TiltangleEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.TiltangleEditFieldLabel.Text = 'Tilt angle';
            app.TiltangleEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.Label = uilabel(app.APIMsUIFigure);
            app.Label.Text = '°';
            app.FindObjectsButton = uibutton(app.APIMsUIFigure, 'push');
            app.FindObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @FindObjectsButtonPushed, true);
            app.FindObjectsButton.Tooltip = {'(f)'};
            app.FindObjectsButton.Text = 'Find Objects';
            app.ExcludeObjectsButton = uibutton(app.APIMsUIFigure, 'push');
            app.ExcludeObjectsButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeObjectsButtonPushed, true);
            app.ExcludeObjectsButton.Tooltip = {'(e)'};
            app.ExcludeObjectsButton.Text = 'Exclude Objects';
            app.CalculatewiresButton = uibutton(app.APIMsUIFigure, 'push');
            app.CalculatewiresButton.ButtonPushedFcn = createCallbackFcn(app, @CalculatewiresButtonPushed, true);
            app.CalculatewiresButton.Tooltip = {'(c)'};
            app.CalculatewiresButton.Text = 'Calculate wires';
            app.PlotResultsButton = uibutton(app.APIMsUIFigure, 'push');
            app.PlotResultsButton.ButtonPushedFcn = createCallbackFcn(app, @PlotResultsButtonPushed, true);
            app.PlotResultsButton.Tooltip = {'(p)'};
            app.PlotResultsButton.Text = 'Plot Results';
            app.FilterbyareaButton = uibutton(app.APIMsUIFigure, 'push');
            app.FilterbyareaButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyareaButtonPushed, true);
            app.FilterbyareaButton.Tooltip = {'(a)'};
            app.FilterbyareaButton.Text = 'Filter by area';
            app.RemoveobjectsEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.RemoveobjectsEditFieldLabel.Text = 'Remove objects <';
            app.RemoveobjectsEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.RemoveobjectsEditField.Limits = [0 Inf];
            app.RemoveobjectsEditField_2Label = uilabel(app.APIMsUIFigure);
            app.RemoveobjectsEditField_2Label.Text = 'Remove objects >';
            app.RemoveobjectsEditField_2 = uieditfield(app.APIMsUIFigure, 'numeric');
            app.RemoveobjectsEditField_2.Limits = [0 Inf];
            app.RemoveobjectsEditField_2.Value = 500;
            app.pixelsAreaLabel = uilabel(app.APIMsUIFigure);
            app.pixelsAreaLabel.Text = 'pixels, Area';
            app.pixelsAreaLabel_2 = uilabel(app.APIMsUIFigure);
            app.pixelsAreaLabel_2.Text = 'pixels, Area';
            app.GraphmakerButton = uibutton(app.APIMsUIFigure, 'push');
            app.GraphmakerButton.ButtonPushedFcn = createCallbackFcn(app, @GraphmakerButtonPushed, true);
            app.GraphmakerButton.Text = 'Graph maker';
            app.MeasurewidthbetweenEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.MeasurewidthbetweenEditFieldLabel.Text = 'Measure width between';
            app.MeasurewidthbetweenEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.MeasurewidthbetweenEditField.Limits = [0 100];
            app.MeasurewidthbetweenEditField.Value = 70;
            app.HeightLabel = uilabel(app.APIMsUIFigure);
            app.HeightLabel.Text = '% ≤ Height ≤';
            app.HeightEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.HeightEditField.Limits = [0 100];
            app.HeightEditField.Value = 90;
            app.Label_2 = uilabel(app.APIMsUIFigure);
            app.Label_2.Text = '%';
            app.RemoveobjectswhoselengthEditFieldLabel = uilabel(app.APIMsUIFigure);
            app.RemoveobjectswhoselengthEditFieldLabel.Text = 'Remove objects whose length <';
            app.RemoveobjectswhoselengthEditField = uieditfield(app.APIMsUIFigure, 'numeric');
            app.RemoveobjectswhoselengthEditField.Limits = [0 Inf];
            app.RemoveobjectswhoselengthEditField.Value = 2;
            app.widthLabel = uilabel(app.APIMsUIFigure);
            app.widthLabel.Text = '× width';
            app.FilterbyratioButton = uibutton(app.APIMsUIFigure, 'push');
            app.FilterbyratioButton.ButtonPushedFcn = createCallbackFcn(app, @FilterbyratioButtonPushed, true);
            app.FilterbyratioButton.Text = 'Filter by ratio';
            app.ExcludeedgesButton = uibutton(app.APIMsUIFigure, 'push');
            app.ExcludeedgesButton.ButtonPushedFcn = createCallbackFcn(app, @ExcludeedgesButtonPushed, true);
            app.ExcludeedgesButton.Tooltip = {'(d)'};
            app.ExcludeedgesButton.Text = 'Exclude edges';
            app.ColorbarLabel = uilabel(app.APIMsUIFigure);
            app.ColorbarLabel.Enable = 'off';
            app.ColorbarLabel.Visible = 'off';
            app.ColorbarLabel.Text = 'Colorbar:';
            app.ColorbarDropDown = uidropdown(app.APIMsUIFigure);
            app.ColorbarDropDown.Items = {'Width (nm)', 'Length (µm)', 'Orientation (°)', 'Volume (nm³)', 'Area (pixels)'};
            app.ColorbarDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorbarDropDownValueChanged, true);
            app.ColorbarDropDown.Enable = 'off';
            app.ColorbarDropDown.Visible = 'off';
            app.ColorbarDropDown.Value = 'Width (nm)';
            app.ColorbarLabel_2 = uilabel(app.APIMsUIFigure);
            app.ColorbarLabel_2.Text = 'Colorbar:';
            app.ColorbarDropDown_2 = uidropdown(app.APIMsUIFigure);
            app.ColorbarDropDown_2.Items = {'Area (pixels)', 'Width (nm)', 'Length (µm)', 'Orientation (°)', 'Volume (nm³)'};
            app.ColorbarDropDown_2.ValueChangedFcn = createCallbackFcn(app, @ColorbarDropDown_2ValueChanged, true);
            app.ColorbarDropDown_2.Value = 'Area (pixels)';
            app.SaveimageButton = uibutton(app.APIMsUIFigure, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Tooltip = {'(s)'};
            app.SaveimageButton.Text = 'Save image';
            % Show the figure after all components are created
            app.APIMsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APIMs_V6_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APIMsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APIMsUIFigure)
        end
    end
end