classdef APIMs_histogram_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        APIMsGraphMakerUIFigure  matlab.ui.Figure
        FileMenu                 matlab.ui.container.Menu
        SaveMenu                 matlab.ui.container.Menu
        ZaxisDropDown            matlab.ui.control.DropDown
        ZaxisDropDownLabel       matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        TocalculatethecurvaturepleaseinputthemeasuredlengthEditField  matlab.ui.control.NumericEditField
        EditFieldLabel           matlab.ui.control.Label
        NumberofbinsSlider       matlab.ui.control.Slider
        NumberofbinsSliderLabel  matlab.ui.control.Label
        FitDropDown              matlab.ui.control.DropDown
        FitDropDownLabel         matlab.ui.control.Label
        TitleEditField           matlab.ui.control.EditField
        TitleEditFieldLabel      matlab.ui.control.Label
        YaxisDropDown            matlab.ui.control.DropDown
        YaxisDropDownLabel       matlab.ui.control.Label
        XaxisDropDown            matlab.ui.control.DropDown
        XaxisDropDownLabel       matlab.ui.control.Label
        GraphtypeDropDown        matlab.ui.control.DropDown
        GraphtypeDropDownLabel   matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        CallingApp % Description
        appdata
    end


    methods (Access = private)

        function RefreshData(app)
            app.appdata = UpdateData(app.CallingApp);

            %             appdata.name = app.name;                            % Name of the file
            %             appdata.file = app.file;                            % Directory + name of the file
            %             appdata.SEM_Image = app.SEM_Image;                  % Matrix of the entire SEM Image
            %             appdata.length_per_pixel = app.length_per_pixel;    % Length per pixel
            %             appdata.tilt_angle = app.tilt_angle;                % Tilt angle
            %             appdata.SEM_data = app.SEM_data;                    % SEM_data
            %             appdata.resolution = app.resolution;                % Resolution of SEM_data
            %             appdata.new_SEM_data = app.new_SEM_data;            % After background removal
            %             appdata.objects = app.objects;                      % Found objects
            %             appdata.folder_to_save_to = app.folder_to_save_to;  % Folder to save data in
            %             appdata.calcstate = app.calcstate;                  % To check whether calculations have been made or not
            %             appdata.imagestate = app.imagestate;                % To check whether image is loaded
            %             appdata.object_type = app.object_type;              % Object type string
        end

        function MakeHistogram(app)
            RefreshData(app);
            if strcmp(app.GraphtypeDropDown.Value, 'Histogram')
                reset(app.UIAxes)
                %pause(0.0001)
                app.XaxisDropDown.Enable = true;
                app.YaxisDropDown.Enable = true;
                app.ZaxisDropDown.Enable = false;
                app.ZaxisDropDown.Visible = false;
                app.ZaxisDropDownLabel.Visible = false;
                app.ZaxisDropDownLabel.Enable = false;
  
                app.TextArea.Value = [strcat("File analyzed: ", app.appdata.name); strcat("No. objects analyzed: ", num2str(length(app.appdata.objects.Area)))];
                %What's going on the x-axis
                what = app.XaxisDropDown.Value;
                if strcmp(what, 'Width')
                    what = app.appdata.objects.PhysicalWidth;
                    what_x_str = 'Width (nm)';
                    app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                    app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean width: ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," nm.")];
                elseif strcmp(what, 'Length')
                    what = app.appdata.objects.PhysicalLength;
                    what_x_str = 'Length (nm)';
                    app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                    app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean length: ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," nm.")];
                elseif strcmp(what, 'Volume')
                    what = app.appdata.objects.PhysicalVolume;
                    what_x_str = 'Volume (nm³)';
                    app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                    app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean volume: ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," nm³.")];
                elseif strcmp(what, 'Orientation')
                    Exist_Column = strcmp('TopViewOrientation',app.appdata.objects.Properties.VariableNames);
                    q_topview_present = Exist_Column(Exist_Column==1);
                    if q_topview_present
                        what = app.appdata.objects.TopViewOrientation;
                        app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                        app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean top-view orientation: ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," °.")];
                    else
                        what = app.appdata.objects.Orientation;
                        app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                        app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean orientation: ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," °.")];
                    end
                    what_x_str = 'Orientation (°)';
                elseif strcmp(what, 'Curvature')
                    if app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField.Value ==0
                        what = app.appdata.objects.RelativeCurvature;
                        app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                        what_x_str = 'Relative curvature (A.U.)';
                        app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean curvature ", num2str(round(mean(what),2))," ± ", num2str(round(std(what),2))," A.U.")];
                    else
                        L = app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField.Value;
                        what = 2*(app.appdata.objects.RelativeCurvature*max(app.appdata.objects.PhysicalLength-app.appdata.objects.PhysicalWidth))/(L^2)*1000;
                        app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                        what_x_str = 'Curvature (µm^{-1})';
                        app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean curvature: ", num2str(round(mean(what),4))," ± ", num2str(round(std(what),5))," µm^{-1}.")];
                    end
                end

                color = 'blue';
                numberofbins = round(app.NumberofbinsSlider.Value);
                %What the y-axis does:
                what_y = app.YaxisDropDown.Value;
                %Histograms
                if strcmp(what_y, 'Counts')
                    histogram(what, numberofbins, 'Parent', app.UIAxes, 'Facecolor',color)
                    app.UIAxes.YLabel.String = 'Counts';
                elseif strcmp(what_y, 'Probability')
                    histogram(what, numberofbins, 'Parent', app.UIAxes, 'Facecolor',color, 'Normalization', 'probability');
                    app.UIAxes.YLabel.String = 'Probability';
                    %Plots
                elseif strcmp(what_y, 'Width')
                    what_y = app.appdata.objects.PhysicalWidth;
                    scatter(what, what_y,'Parent', app.UIAxes)
                    app.UIAxes.YLabel.String = 'Width (nm)';
                elseif strcmp(what_y, 'Length')
                    what_y = app.appdata.objects.PhysicalLength;
                    scatter(what, what_y,'Parent', app.UIAxes)
                    app.UIAxes.YLabel.String = 'Length (nm)';
                elseif strcmp(what_y, 'Volume')
                    what_y = app.appdata.objects.PhysicalVolume;
                    scatter(what, what_y,'Parent', app.UIAxes)
                    app.UIAxes.YLabel.String = 'Volume (nm³)';
                elseif strcmp(what_y, 'Orientation')
                    Exist_Column = strcmp('TopViewOrientation',app.appdata.objects.Properties.VariableNames);
                    q_topview_present = Exist_Column(Exist_Column==1);
                    if q_topview_present
                        what_y = app.appdata.objects.TopViewOrientation;
                        app.NumberofbinsSlider.Limits = [1, length(unique(round(app.appdata.objects.TopViewOrientation)))];
                    else
                        what_y = app.appdata.objects.Orientation;
                        app.NumberofbinsSlider.Limits = [1, length(unique(round(app.appdata.objects.Orientation)))];
                    end
                    scatter(what, what_y,'Parent', app.UIAxes)
                    app.UIAxes.YLabel.String = 'Orientation (°)';
                end
                app.UIAxes.XLabel.String = what_x_str;
                
                %Fit a distribution
                %L = 8800;
                %what = 2*(app.appdata.objects.RelativeCurvature*max(app.appdata.objects.PhysicalLength-app.appdata.objects.PhysicalWidth))/(L^2)*1000;
                %app.NumberofbinsSlider.Limits = [1, min([length(unique(what)),100])];
                %app.UIAxes.XLabel.String = 'Curvature (µm^{-1})';
                %app.TextArea.Value = [app.TextArea.Value;''; strcat("Mean curvature: ", num2str(round(mean(what),4))," ± ", num2str(round(std(what),5))," µm^{-1}.")];
                %fitdist(what,'InverseGaussian', 'Parent', app.UIAxes)


                %Title
                app.UIAxes.Title.Interpreter ='none';
                if isempty(app.TitleEditField.Value)
                    app.UIAxes.Title.String = app.appdata.name;
                else
                    app.UIAxes.Title.String = app.TitleEditField.Value;
                end
            else
                WidthFuncPos(app)
            end
        end
        
        function WidthFuncPos(app)
            objects = app.appdata.objects;
            %width as a function of spatial position
            app.XaxisDropDown.Enable = false;
            app.YaxisDropDown.Enable = false;
            app.ZaxisDropDown.Enable = true;
            app.ZaxisDropDown.Visible = true;
            app.ZaxisDropDownLabel.Visible = true;
            app.ZaxisDropDownLabel.Enable = true;

            if strcmp('Width', app.ZaxisDropDown.Value)
                for i = 1:length(objects.Area)
                    x(i) = objects.Centroid(i,1);
                    y(i) = objects.Centroid(i,2);
                    z(i) = objects.PhysicalWidth(i);
                end
                what_z = 'Width (nm)';
            elseif strcmp('Length', app.ZaxisDropDown.Value)
                counter=1;
                for i = 1:length(objects.Area)
                    if objects.PhysicalLength(i)>0
                        x(counter) = objects.Centroid(i,1);
                        y(counter) = objects.Centroid(i,2);
                        z(counter) = objects.PhysicalLength(i);
                        counter = counter+1;
                    end
                    what_z = 'Length (nm)';
                end
            elseif strcmp('Volume', app.ZaxisDropDown.Value)
                counter=1;
                for i = 1:length(objects.Area)
                    if objects.PhysicalLength(i)>0
                        x(counter) = objects.Centroid(i,1);
                        y(counter) = objects.Centroid(i,2);
                        z(counter) = objects.PhysicalVolume(i);
                        counter = counter+1;
                    end
                    what_z = 'Volume (nm3)';
                end
            elseif strcmp('Curvature', app.ZaxisDropDown.Value)
                if app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField.Value ==0
                        what = app.appdata.objects.RelativeCurvature;
                        what_z = 'Curvature (A.U.)';
                    else
                        L = app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField.Value;
                        what = 2*(app.appdata.objects.RelativeCurvature*max(app.appdata.objects.PhysicalLength-app.appdata.objects.PhysicalWidth))/(L^2)*1000;
                        what_z = 'Curvature (µm^{-1})';
                end
                counter = 1;
                for i = 1:length(objects.Area)
                    if what(i)>0
                        x(counter) = objects.Centroid(i,1);
                        y(counter) = objects.Centroid(i,2);
                        z(counter) = what(i);
                        counter = counter+1;
                    end
                end
            end

            xv = linspace(min(x), max(x), 20);
            yv = linspace(min(y), max(y), 20);
            [X,Y] = meshgrid(xv, yv);
            %Set Y to correspond intuitively correctly to the original
            %image:
            %Y = -Y+max(Y);
            Z = griddata(x,y,z,X,Y);
            surf(X, -Y+max(Y), Z, 'Parent', app.UIAxes);
            %grid on
            app.UIAxes.ZLim = [min(min(Z))-0.1*min(min(Z)) max(max(Z))+0.1*max(max(Z))];
             app.UIAxes.XLabel.String = 'x-position';
             app.UIAxes.YLabel.String = 'y-position';
             app.UIAxes.ZLabel.String = what_z;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.CallingApp = mainapp;
            RefreshData(app);
            MakeHistogram(app)
            app.NumberofbinsSlider.Limits = [1, length(unique(round(app.appdata.objects.PhysicalWidth)))];
        end

        % Callback function
        function ButtonPushed(app, event)
            RefreshData(app);
            app.TextArea.Value = app.appdata.file;
            %app.filename = file;
        end

        % Callback function
        function Button2Pushed(app, event)
            RefreshData(app);
            app.TextArea.Value = num2str(app.appdata.tilt_angle);
            
        end

        % Value changed function: GraphtypeDropDown, NumberofbinsSlider, 
        % XaxisDropDown, YaxisDropDown, ZaxisDropDown
        function PlotButtonPushed(app, event)
            RefreshData(app);
            MakeHistogram(app)
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            MakeHistogram(app)
        end

        % Value changed function: 
        % TocalculatethecurvaturepleaseinputthemeasuredlengthEditField
        function TocalculatethecurvaturepleaseinputthemeasuredlengthEditFieldValueChanged(app, event)
            MakeHistogram(app)
        end

        % Callback function
        function ButtonPushed2(app, event)
            WidthFuncPos(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.APIMsGraphMakerUIFigure = uifigure('Visible', 'off');
            app.APIMsGraphMakerUIFigure.Name = 'APIMs GraphMaker';
            app.APIMsGraphMakerUIFigure.Icon = 'APIMs_Icon.png';
            app.FileMenu = uimenu(app.APIMsGraphMakerUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save...';
            app.UIAxes = uiaxes(app.APIMsGraphMakerUIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GraphtypeDropDownLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.GraphtypeDropDownLabel.Text = 'Graph type';
            app.GraphtypeDropDown = uidropdown(app.APIMsGraphMakerUIFigure);
            app.GraphtypeDropDown.Items = {'Histogram', '3D plot'};
            app.GraphtypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.GraphtypeDropDown.Value = 'Histogram';
            app.XaxisDropDownLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.XaxisDropDownLabel.Text = 'X-axis';
            app.XaxisDropDown = uidropdown(app.APIMsGraphMakerUIFigure);
            app.XaxisDropDown.Items = {'Width', 'Length', 'Volume', 'Orientation', 'Curvature'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.XaxisDropDown.Value = 'Width';
            app.YaxisDropDownLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.YaxisDropDownLabel.Text = 'Y-axis';
            app.YaxisDropDown = uidropdown(app.APIMsGraphMakerUIFigure);
            app.YaxisDropDown.Items = {'Counts', 'Probability', 'Width', 'Length', 'Volume', 'Orientation'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.YaxisDropDown.Value = 'Counts';
            app.TitleEditFieldLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField = uieditfield(app.APIMsGraphMakerUIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.FitDropDownLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.FitDropDownLabel.Text = 'Fit';
            app.FitDropDown = uidropdown(app.APIMsGraphMakerUIFigure);
            app.FitDropDown.Items = {'None', 'Inverse Gaussian', 'Gaussian', 'Normal', 'Poisson'};
            app.FitDropDown.Value = 'None';
            app.NumberofbinsSliderLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.NumberofbinsSliderLabel.Text = 'Number of bins';
            app.NumberofbinsSlider = uislider(app.APIMsGraphMakerUIFigure);
            app.NumberofbinsSlider.Limits = [1 100];
            app.NumberofbinsSlider.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.NumberofbinsSlider.Value = 50;
            app.EditFieldLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.EditFieldLabel.Text = 'To calculate real curvature, please input the measured length in nm:';
            app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField = uieditfield(app.APIMsGraphMakerUIFigure, 'numeric');
            app.TocalculatethecurvaturepleaseinputthemeasuredlengthEditField.ValueChangedFcn = createCallbackFcn(app, @TocalculatethecurvaturepleaseinputthemeasuredlengthEditFieldValueChanged, true);
            app.TextArea = uitextarea(app.APIMsGraphMakerUIFigure);
            app.ZaxisDropDownLabel = uilabel(app.APIMsGraphMakerUIFigure);
            app.ZaxisDropDownLabel.Enable = 'off';
            app.ZaxisDropDownLabel.Visible = 'off';
            app.ZaxisDropDownLabel.Text = 'Z-axis';
            app.ZaxisDropDown = uidropdown(app.APIMsGraphMakerUIFigure);
            app.ZaxisDropDown.Items = {'Width', 'Length', 'Volume', 'Curvature'};
            app.ZaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.ZaxisDropDown.Enable = 'off';
            app.ZaxisDropDown.Visible = 'off';
            app.ZaxisDropDown.Value = 'Width';
            % Show the figure after all components are created
            app.APIMsGraphMakerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APIMs_histogram_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.APIMsGraphMakerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.APIMsGraphMakerUIFigure)
        end
    end
end